classdef qualitycheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectFolderButton  matlab.ui.control.Button
        FolderEditField     matlab.ui.control.EditField
        ListBox             matlab.ui.control.ListBox
        UIAxes              matlab.ui.control.UIAxes
        CheckButton         matlab.ui.control.Button
        MarkerDropDown      matlab.ui.control.DropDown
        ConsistencyLamp     matlab.ui.control.Lamp
        EditField           matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        mnames = {}; % Description
        data = {};
    end

    methods (Access = private)
    
        function updateaxis(app)
            for j = 1:length(app.MarkerDropDown.Items)
                if strcmp(app.MarkerDropDown.Items{j}, app.MarkerDropDown.Value)
                    idx = j;
                    break
                end
            end
            idx_used_sample = zeros(length(app.ListBox.Value), 1);
            k = 1;
            for j = 1:length(app.ListBox.Items)
                if ~any(strcmp(app.ListBox.Value, app.ListBox.Items{j}))
                    idx_used_sample(k) = j;
                    k = k +1;
                end
            end
            c = hsv(length(app.data));
            cla(app.UIAxes);
            hold( app.UIAxes, 'on');
            maxf = zeros(length(idx_used_sample), 1);
            cx = cell(length(idx_used_sample), 1);
            for i = 1:length(idx_used_sample)
                d = app.data{idx_used_sample(i)};
                [f,xi] = ksdensity(d(:,idx),'Kernel', 'box');
                plot( app.UIAxes, xi, f, 'color', c(idx_used_sample(i),:));
                maxf(i) = max(f);
                cx{i} = d(:,idx);
            end
            x = cell2mat(cx);
            xlim(app.UIAxes, [prctile(x, 1) prctile(x, 99)]);
            ylim(app.UIAxes, [0 max(maxf)]);
            xlabel(app.UIAxes, app.MarkerDropDown.Value);
            ylabel(app.UIAxes, 'Frequency');
            title(app.UIAxes, app.MarkerDropDown.Value);
            hold(app.UIAxes, 'off');
        end
        
    end


    methods (Access = private)

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            
            app.mnames = {};
            folder = [app.FolderEditField.Value '\'];
            app.ConsistencyLamp.Color = 'y';
            for i = 1:length(app.ListBox.Items)
                fname = app.ListBox.Items{i};
                [~, mname,~,~,d] = readfcs_v2([folder fname]);
                if app.EditField.Value > 0
                    d = flow_arcsinh(d, app.EditField.Value)';
                end
                app.mnames{i} = mname;
                app.data{i} = d;
                if i > 1 && ~isempty(setdiff(app.mnames{1}, mname))
                    app.ConsistencyLamp.Color = 'r';
                end
            end
            if app.ConsistencyLamp.Color ~= 'y'
                app.ConsistencyLamp.Color = 'g';
            end
            app.MarkerDropDown.Items = app.mnames{1};
            app.updateaxis;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            if isempty(app.MarkerDropDown.Value)
                return
            end
            app.updateaxis;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            
            folder_name = uigetdir('D:\OneDrive\MyProject\RGD Hydrogel\Compensated FCS files for HD analysis - days 3, 7, 14');
            app.FolderEditField.Value = folder_name;
            files = dir([app.FolderEditField.Value '\*.fcs']);
            fname = {files.name};
            app.ListBox.Items = fname;   
            app.ListBox.Value = {};
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.updateaxis;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Value = '.';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.MarkerDropDown = uidropdown(app.UIFigure);
            app.MarkerDropDown.Items = {};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = {};
            app.ConsistencyLamp = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = qualitycheck

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end